classdef StokesSimulator < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                  matlab.ui.Figure
    AspectRatioDisplay        matlab.ui.control.TextArea
    AspectRatioSlider         matlab.ui.control.Slider
    AspectRatioSliderLabel    matlab.ui.control.Label
    DensityDisplay            matlab.ui.control.TextArea
    MineralogyDropDown        matlab.ui.control.DropDown
    MineralogyDropDownLabel   matlab.ui.control.Label
    ElevationDisplay          matlab.ui.control.TextArea
    ElevationSlider           matlab.ui.control.Slider
    ElevationmAGLSliderLabel  matlab.ui.control.Label
    UIAxes                    matlab.ui.control.UIAxes
  end

  
  properties (Access = private)
    rho_p % Density of an average particle (kg/m^3)
    plt % Plot handle
  end
  
  methods (Access = private)
   
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      % Set display fields to "no edit"
      app.ElevationDisplay.Editable='off';
      app.DensityDisplay.Editable='off';
      app.AspectRatioDisplay.Editable='off';
      % Define particle density
      app.rho_p = particle_density(app.MineralogyDropDown.Value); % kg/m^3
      % Plot the settling velocity
      app.ElevationDisplay.Value = '0 m';
      C = mineral_color(app.MineralogyDropDown.Value);
      app.plt=plot(app.UIAxes,app.UIAxes.XTick,stokes_law_model(app.rho_p, 0),...
        'o-','LineWidth',2,'MarkerFaceColor','auto','Color',C);
      app.UIAxes.XMinorTick = 'off';
      ylim(app.UIAxes,[0 max(app.plt.YData)])
      yt = floor(linspace(0,max(app.plt.YData),8));
      yticks(app.UIAxes,yt);
      % Set mineralogy density display
      app.DensityDisplay.Value = sprintf('%g kg/m^3', app.rho_p);
      % Set aspect ratio display
      app.AspectRatioDisplay.Value = sprintf('%0.1f',app.AspectRatioSlider.Value);
    end

    % Value changing function: ElevationSlider
    function ElevationSliderValueChanging(app, event)
      new_elevation = event.Value;
      app.ElevationDisplay.Value = sprintf('%d m',floor(new_elevation));
      C = mineral_color(app.MineralogyDropDown.Value);
      app.plt=plot(app.UIAxes,app.UIAxes.XTick,stokes_law_model(app.rho_p, new_elevation),...
        'o-','LineWidth',2,'MarkerFaceColor','auto','Color',C);
      app.UIAxes.XMinorTick = 'off';
      ylim(app.UIAxes,[0 max(app.plt.YData)])
      yt = floor(linspace(0,max(app.plt.YData),8));
      yticks(app.UIAxes,yt)
    end

    % Value changed function: MineralogyDropDown
    function MineralogyDropDownValueChanged(app, event)
      app.rho_p = particle_density(app.MineralogyDropDown.Value); % kg/m^3
      C = mineral_color(app.MineralogyDropDown.Value);
      app.plt=plot(app.UIAxes,app.UIAxes.XTick,stokes_law_model(app.rho_p, app.ElevationSlider.Value),...
        'o-','LineWidth',2,'MarkerFaceColor','auto','Color',C);
      app.UIAxes.XMinorTick = 'off';
      ylim(app.UIAxes,[0 max(app.plt.YData)])
      yt = floor(linspace(0,max(app.plt.YData),8));
      yticks(app.UIAxes,yt)
      % Update density display
      app.DensityDisplay.Value = sprintf('%g kg/m^3', app.rho_p);
    end

    % Value changing function: AspectRatioSlider
    function AspectRatioSliderValueChanging(app, event)
      aspect_ratio = event.Value;
      % Update display value
      app.AspectRatioDisplay.Value = sprintf('%0.1f',aspect_ratio);
      % Get size data
      xData = app.plt.XData;
      % Make adjustment to settling velocity based on updated aspect ratio
      adjustments = zeros(size(xData))';
      for x = 1:length(xData)
        adj = aspect_ratio_adjustment(xData(x)./2, aspect_ratio);
        adjustments(x) = adj;
      end
      % Add data back to the plot
      C = mineral_color(app.MineralogyDropDown.Value);
      app.plt=plot(app.UIAxes,app.UIAxes.XTick, ...
        stokes_law_model(app.rho_p, app.ElevationSlider.Value).*adjustments,...
        'o-','LineWidth',2,'MarkerFaceColor','auto','Color',C);
      app.UIAxes.XMinorTick = 'off';
      ylim(app.UIAxes,[0 max(app.plt.YData)])
      yt = floor(linspace(0,max(app.plt.YData),8));
      yticks(app.UIAxes,yt)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIAxes = uiaxes(app.UIFigure);
      title(app.UIAxes, 'Stokes'' Law - Dust Settling Velocity')
      xlabel(app.UIAxes, 'Particle Diameter (µm)')
      ylabel(app.UIAxes, 'Velocity (meters/hour)')
      app.UIAxes.XLim = [0.5 20];
      app.UIAxes.XTick = [0.63 0.8 1 1.26 1.59 2.02 2.52 3.14 4 5.04 6.35 8 10.07 12.7 16];
      app.UIAxes.XScale = 'log';
      app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
      app.ElevationmAGLSliderLabel = uilabel(app.UIFigure);
      app.ElevationmAGLSliderLabel.Text = {'Elevation'; '(m AGL)'};
      app.ElevationSlider = uislider(app.UIFigure);
      app.ElevationSlider.Limits = [0 8000];
      app.ElevationSlider.MajorTicks = [0 1000 2000 3000 4000 5000 6000 7000 8000];
      app.ElevationSlider.ValueChangingFcn = createCallbackFcn(app, @ElevationSliderValueChanging, true);
      app.ElevationSlider.MinorTicks = [0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000 3250 3500 3750 4000 4250 4500 4750 5000 5250 5500 5750 6000 6250 6500 6750 7000 7250 7500 7750 8000];
      app.ElevationDisplay = uitextarea(app.UIFigure);
      app.MineralogyDropDownLabel = uilabel(app.UIFigure);
      app.MineralogyDropDownLabel.Text = 'Mineralogy';
      app.MineralogyDropDown = uidropdown(app.UIFigure);
      app.MineralogyDropDown.Items = {'Average Crustal', 'Calcite', 'Chlorite', 'Feldspar', 'Fe-oxides', 'Goethite', 'Gypsum', 'Hematite', 'Illite', 'Kaolinite', 'Mica', 'Smectite', 'Quartz', 'Vermiculite'};
      app.MineralogyDropDown.ValueChangedFcn = createCallbackFcn(app, @MineralogyDropDownValueChanged, true);
      app.MineralogyDropDown.Value = 'Average Crustal';
      app.DensityDisplay = uitextarea(app.UIFigure);
      app.AspectRatioSliderLabel = uilabel(app.UIFigure);
      app.AspectRatioSliderLabel.Text = {'Aspect'; 'Ratio'};
      app.AspectRatioSlider = uislider(app.UIFigure);
      app.AspectRatioSlider.Limits = [1 10];
      app.AspectRatioSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
      app.AspectRatioSlider.ValueChangingFcn = createCallbackFcn(app, @AspectRatioSliderValueChanging, true);
      app.AspectRatioSlider.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
      app.AspectRatioSlider.Value = 1;
      app.AspectRatioDisplay = uitextarea(app.UIFigure);
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = StokesSimulator

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end